classdef GraphicalResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InductionMotorAnalyisisUIFigure  matlab.ui.Figure
        Torque          matlab.ui.control.UIAxes
        ExportButton    matlab.ui.control.Button
        InfoButton      matlab.ui.control.Button
        StatorCurrents  matlab.ui.control.UIAxes
        RotorCurrents   matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function updateplot(app) %Leave them commented, I use them as reference.
            % Get Table UI component data
          %  t = app.UITable.DisplayData;            
            % Plot modified data
           % x2 = t.Age;
            %y2 = t.BloodPressure(:,2);
            %plot(app.StatorCurrents,x2,y2,'-o');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %ignore this, just as a reference for me
            %assignin('base','Ampl',app.AmplitudeSlider.Value);
            %simout=sim('SineWave_SL','StopTime', num2str(app.DurationSlider.Value));
            %plot(app.Torque,simout.SineWaveValue.Time, simout.SineWaveValue.Data);
            
            %This part allows us to get the graphs from the test model.
            simout=sim('RTinductionmotorJan16.slx');
            plot(app.Torque,simout.Torque_output.Time,simout.Torque_output.Data);
            plot(app.StatorCurrents,simout.i_ds_output.Time,simout.i_ds_output.Data,simout.i_qs_output.Time,simout.i_qs_output.Data);
            plot(app.RotorCurrents,simout.i_dr_output.Time,simout.i_dr_output.Data,simout.i_qr_output.Time,simout.i_qr_output.Data);
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            close(app.InductionMotorAnalyisisUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InductionMotorAnalyisisUIFigure = uifigure('Visible', 'off');
            app.InductionMotorAnalyisisUIFigure.Name = 'Induction Motor Analyisis';
            app.Torque = uiaxes(app.InductionMotorAnalyisisUIFigure);
            title(app.Torque, {'Electro-magnetic torque (Nm)'; ''})
            xlabel(app.Torque, 'Speed (rpm)')
            ylabel(app.Torque, '')
            app.Torque.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29];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
            app.Torque.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'; '1'; '0'};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
            app.Torque.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29];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
            app.Torque.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'; '65'; '66'; '67'; '68'; '69'; '70'; '71'; '72'; '73'; '74'; '75'; '76'; '77'; '78'; '79'; '80'; '81'; '82'; '83'; '84'; '85'; '86'; '87'; '88'; '89'; '90'; '91'; '92'; '93'; '94'; '95'; '96'; '97'; '98'; '99'; '100'; '101'};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
            app.ExportButton = uibutton(app.InductionMotorAnalyisisUIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.InfoButton = uibutton(app.InductionMotorAnalyisisUIFigure, 'push');
            app.InfoButton.Text = 'Info';
            app.StatorCurrents = uiaxes(app.InductionMotorAnalyisisUIFigure);
            title(app.StatorCurrents, 'Stator Currents')
            xlabel(app.StatorCurrents, 'X')
            ylabel(app.StatorCurrents, 'Y')
            app.StatorCurrents.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.RotorCurrents = uiaxes(app.InductionMotorAnalyisisUIFigure);
            title(app.RotorCurrents, 'Rotor Currents')
            xlabel(app.RotorCurrents, 'X')
            ylabel(app.RotorCurrents, 'Y')
            % Show the figure after all components are created
            app.InductionMotorAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicalResults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InductionMotorAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InductionMotorAnalyisisUIFigure)
        end
    end
end