classdef Motor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        POWERSYSTEMPanel          matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        Edit_R1                   matlab.ui.control.NumericEditField
        Edit_X2                   matlab.ui.control.NumericEditField
        Edit_R2_S                 matlab.ui.control.NumericEditField
        Edit_XM                   matlab.ui.control.NumericEditField
        Edit_X1                   matlab.ui.control.NumericEditField
        PHASEINDUCTIONMOTORLabel  matlab.ui.control.Label
        PolesSpinnerLabel         matlab.ui.control.Label
        PolesSpinner              matlab.ui.control.Spinner
        FrequencySliderLabel      matlab.ui.control.Label
        FrequencySlider           matlab.ui.control.Slider
        SetParametersButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % Object of the app that calls this activity so that they can exchange info
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SetParametersButton.Enable = 'off';        
            
            % Old method tried before:
            %set_param(test,'X1',str2double(app.Edit_X1.Value)); 
            
            % New method that works right now:
            
            % The function "assignin(ws,var,val)" assigns a value
            % "val" to a variable "var" in the workspace "ws"
            % In this case, it creates the variables in the "base"
            % workspace of Matlab and therefore any variable in the same
            % folder (Simulink model will be in the same folder),
            %  will get that value assigned.
            
            assignin("base","Rs",app.Edit_R1.Value);            
            assignin("base","Ls",app.Edit_X1.Value);
            assignin("base","Rr",app.Edit_R2_S.Value);
            assignin("base","Lr",app.Edit_X2.Value);
            assignin("base","Lm",app.Edit_XM.Value);
            
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            assignin("base","Fe",value);
        end

        % Value changed function: PolesSpinner
        function PolesSpinnerValueChanged(app, event)
            value = app.PolesSpinner.Value;
            assignin("base","Poles",value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.MotorButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.POWERSYSTEMPanel = uipanel(app.UIFigure);
            app.POWERSYSTEMPanel.Title = 'POWER SYSTEM';
            app.Image = uiimage(app.POWERSYSTEMPanel);
            app.Image.ImageSource = 'EquivalentCircuitDiagram.png';
            app.Edit_R1 = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_R1.Value = 1;
            app.Edit_X2 = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_X2.Value = 0.009061;
            app.Edit_R2_S = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_R2_S.Value = 1.06;
            app.Edit_XM = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_XM.Value = 0.1485;
            app.Edit_X1 = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_X1.Value = 0.003883;
            app.PHASEINDUCTIONMOTORLabel = uilabel(app.POWERSYSTEMPanel);
            app.PHASEINDUCTIONMOTORLabel.Text = '3-PHASE INDUCTION MOTOR';
            app.PolesSpinnerLabel = uilabel(app.POWERSYSTEMPanel);
            app.PolesSpinnerLabel.Text = 'Poles';
            app.PolesSpinner = uispinner(app.POWERSYSTEMPanel);
            app.PolesSpinner.ValueChangedFcn = createCallbackFcn(app, @PolesSpinnerValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.POWERSYSTEMPanel);
            app.FrequencySliderLabel.Text = 'Frequency ';
            app.FrequencySlider = uislider(app.POWERSYSTEMPanel);
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.SetParametersButton = uibutton(app.POWERSYSTEMPanel, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end