classdef GridLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GridLaboratoryUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        PhaseCurrentPanel            matlab.ui.container.Panel
        GridLayout21_2               matlab.ui.container.GridLayout
        AbsoluteEditField_4Label     matlab.ui.control.Label
        PhaseAbsolute                matlab.ui.control.EditField
        AbsoluteEditField_5Label     matlab.ui.control.Label
        PhaseAbsoluteEditField       matlab.ui.control.EditField
        TotalImpedancePanel          matlab.ui.container.Panel
        GridLayout21_3               matlab.ui.container.GridLayout
        ZEditField_2Label            matlab.ui.control.Label
        ZEditField_2                 matlab.ui.control.EditField
        AbsoluteEditField_6Label     matlab.ui.control.Label
        ImpedanceAbsolute            matlab.ui.control.EditField
        AngleEditField_2Label_2      matlab.ui.control.Label
        ImpedanceAngle               matlab.ui.control.EditField
        PerformancePanel             matlab.ui.container.Panel
        GridLayout21_4               matlab.ui.container.GridLayout
        SlipEditFieldLabel           matlab.ui.control.Label
        SlipEditField                matlab.ui.control.EditField
        PFEditField_2Label           matlab.ui.control.Label
        PFEditField_2                matlab.ui.control.EditField
        EfficiencyEditFieldLabel     matlab.ui.control.Label
        EfficiencyEditField          matlab.ui.control.EditField
        PowerPanel                   matlab.ui.container.Panel
        GridLayout30                 matlab.ui.container.GridLayout
        DeliveredPowerPoutEditFieldLabel  matlab.ui.control.Label
        DeliveredPowerPoutEditField  matlab.ui.control.NumericEditField
        TorquePanel                  matlab.ui.container.Panel
        GridLayout30_2               matlab.ui.container.GridLayout
        TotalMechanicalTorqueNmEditField_2Label  matlab.ui.control.Label
        TotalMechanicalTorqueNmEditField_2  matlab.ui.control.NumericEditField
        MotorCharacteristicsPanel    matlab.ui.container.Panel
        GridLayout28                 matlab.ui.container.GridLayout
        FrequencyEditField           matlab.ui.control.NumericEditField
        NumberofPolesSpinnerLabel    matlab.ui.control.Label
        NumberofPolesSpinner         matlab.ui.control.Spinner
        FrequencyHzSliderLabel       matlab.ui.control.Label
        FrequencyHzSlider            matlab.ui.control.Slider
        Image                        matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel     matlab.ui.control.Label
        GridVoltage                  matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        VrmsGridVKnob                matlab.ui.control.Knob
        VbLamp_11                    matlab.ui.control.Lamp
        VaLabel                      matlab.ui.control.Label
        VbLamp_12                    matlab.ui.control.Lamp
        VbLamp_13                    matlab.ui.control.Lamp
        VbLabel                      matlab.ui.control.Label
        VcLabel                      matlab.ui.control.Label
        VrmsEditFieldLabel           matlab.ui.control.Label
        VrmsEditField                matlab.ui.control.NumericEditField
        VoltageLabel                 matlab.ui.control.Label
        ParametersOhmsPanel          matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        jXsEditFieldLabel            matlab.ui.control.Label
        jXsEditField                 matlab.ui.control.NumericEditField
        jXmEditFieldLabel            matlab.ui.control.Label
        jXmEditField                 matlab.ui.control.NumericEditField
        jXrEditFieldLabel            matlab.ui.control.Label
        jXrEditField                 matlab.ui.control.NumericEditField
        RrEditFieldLabel             matlab.ui.control.Label
        RrEditField                  matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        SetParametersButton          matlab.ui.control.Button
        MotorLoadPanel               matlab.ui.container.Panel
        GridLayout16                 matlab.ui.container.GridLayout
        LoadTorqueLabel              matlab.ui.control.Label
        LoadTorqueKnob               matlab.ui.control.Knob
        TorqueNmLabel                matlab.ui.control.Label
        LoadTorqueEditField          matlab.ui.control.NumericEditField
        DefaultParametersButton      matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        EraseAllButton               matlab.ui.control.Button
        SetParametersMessageBox      matlab.ui.control.EditField
        Panel_3                      matlab.ui.container.Panel
        GridLayout26                 matlab.ui.container.GridLayout
        FuseLampLabel                matlab.ui.control.Label
        FuseLamp                     matlab.ui.control.Lamp
        ResultsTab                   matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        TabGroup2                    matlab.ui.container.TabGroup
        GeneralResultsTab            matlab.ui.container.Tab
        GridLayout23                 matlab.ui.container.GridLayout
        GraphGeneralVoltage          matlab.ui.control.UIAxes
        GraphGeneralCurrent          matlab.ui.control.UIAxes
        GraphGeneralSpeed            matlab.ui.control.UIAxes
        GraphGeneralTorque           matlab.ui.control.UIAxes
        ElectromagneticTorqueTab     matlab.ui.container.Tab
        GridLayout20                 matlab.ui.container.GridLayout
        GraphTabTorque               matlab.ui.control.UIAxes
        SpeedTab                     matlab.ui.container.Tab
        GridLayout25                 matlab.ui.container.GridLayout
        GraphTabSpeed                matlab.ui.control.UIAxes
        PhaseCurrentsStatorTab       matlab.ui.container.Tab
        GridLayout22                 matlab.ui.container.GridLayout
        GraphTabCurrent              matlab.ui.control.UIAxes
        VoltageTab                   matlab.ui.container.Tab
        GridLayout24                 matlab.ui.container.GridLayout
        GraphTabVoltage              matlab.ui.control.UIAxes
        RefreshButton                matlab.ui.control.Button
        TimeStopSliderLabel          matlab.ui.control.Label
        TimeStopSlider               matlab.ui.control.Slider
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout21                 matlab.ui.container.GridLayout
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
        StopTimesDropDownLabel       matlab.ui.control.Label
        StopTimesDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        callingApp % Description
        simulated % Description
    end
    
    methods (Access = private)
        
        function plotGraphs(app)
            
            %----General Tab------- ClosedLoopApril1/Motor Model/Rotor speed
            %REFERENCE
            %legend(app.UIAxes_Stator,({'stator current/i-qs','stator current/i-ds'}));
            plot(app.GraphGeneralVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphGeneralVoltage,({'Va','Vb','Vc'}));
            grid(app.GraphGeneralVoltage,'on');
            
            plot(app.GraphGeneralCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphGeneralCurrent,({'Ia','Ib','Ic'}));
            grid(app.GraphGeneralCurrent,'on');
            
            plot(app.GraphGeneralSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            grid(app.GraphGeneralSpeed,'on');
            
            plot(app.GraphGeneralTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
            grid(app.GraphGeneralTorque,'on');
            %---- Each Specific Tab----
            
            plot(app.GraphTabTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
            grid(app.GraphTabTorque,'on');
            
            plot(app.GraphTabSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            grid(app.GraphTabSpeed,'on');
            
            plot(app.GraphTabVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphTabVoltage,({'Va','Vb','Vc'}));
            grid(app.GraphTabVoltage,'on');
            
            plot(app.GraphTabCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphTabCurrent,({'Ia','Ib','Ic'}));
            grid(app.GraphTabCurrent,'on');
            
        end
        frequency;    
        function DisplaySpecificValues(app)%needed to calculare slip whern start button actionned
            freqArray = app.simulated.get('freqS');
            fs = freqArray(1);   
            frequency=fs;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.GridLaboratoryUIFigure,'center');
            load_system('simulation\ClosedLoopApril1.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            set_param([bdroot,'/SourceSelect'],'Value',num2str(0));
            
            app.FrequencyHzSlider.Value=0;
        end

        % Value changed function: NumberofPolesSpinner
        function NumberofPolesSpinnerValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Value changing function: FrequencyHzSlider
        function FrequencyHzSliderValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
             
            freqSlider = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            % round() avoids gettings values like 45.4168751
           
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freqSlider);
            
            app.FrequencyEditField.Value = str2double(freqSlider);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            valueEdit = app.FrequencyEditField.Value;
           
            freq = num2str(valueEdit);
            
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);
            
            app.FrequencyHzSlider.Value = str2double(freq);
            
        end

        % Callback function
        function SpeedrpmEditFieldValueChanged(app, event)
            value = app.SpeedrpmEditField.Value;
            
            mspeed = num2str(value);
            set_param([bdroot,'/Vbyf with PWM/motor speed'],'Value', mspeed);
            
            
        end

        % Value changed function: VrmsGridVKnob
        function VrmsGridValueChanging(app, event)
            changingValue = event.Value;
            
            voltKnob = num2str(round(changingValue,1)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/GridVoltage'],'Value', voltKnob);

            app.VrmsEditField.Value =  str2double(voltKnob);
        end

        % Value changed function: VrmsEditField
        function VrmsEditFieldValueChanged(app, event)
            valueEdit = app.VrmsEditField.Value;
            
            Vrms = num2str(valueEdit);
           
            set_param([bdroot,'/GridVoltage'],'Value', Vrms);
            
            app.VrmsGridVKnob.Value= str2double(Vrms);
        end

        % Value changed function: LoadTorqueKnob
        function LoadTorqueKnobValueChanging(app, event)
            changingValue = event.Value;
            
            loadKnob = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadKnob);
           
            app.LoadTorqueEditField.Value =  str2double(loadKnob);
        end

        % Value changed function: LoadTorqueEditField
        function LoadTorqueEditFieldValueChanged(app, event)
            valueEdit = app.LoadTorqueEditField.Value;
            
            loadTorque = num2str(round(valueEdit));
           
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadTorque);
            
            app.LoadTorqueKnob.Value = str2double(loadTorque);
        end

        % Button pushed function: DefaultParametersButton
        function DefaultParametersButtonPushed(app, event)
            app.RsEditField.Value=0.6832;
            app.jXsEditField.Value=0.002817; %Changed
            app.jXmEditField.Value=0.114; %Changed
            app.jXrEditField.Value=0.0042; %Changed
            app.RrEditField.Value=0.7265; %Changed
            app.FrequencyHzSlider.Value=60;
            app.FrequencyEditField.Value = app.FrequencyHzSlider.Value;
            app.NumberofPolesSpinner.Value=2; %Changed
            %app.SpeedrpmEditField.Value=3450;
            app.VrmsGridVKnob.Value=220;
            app.VrmsEditField.Value=220;
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            %app.SetParametersButton.Enable = 'off';        
           
            % Setting the Resistances and Inductances 
                       
            Rs = num2str(app.RsEditField.Value);
            Ls = num2str(app.jXsEditField.Value);
            Rr = num2str(app.RrEditField.Value);           
            Lr = num2str(app.jXrEditField.Value);
            Lm = num2str(app.jXmEditField.Value);
            
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls); 
            set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            
            % Setting the Poles
            
            polesSpinner = num2str(app.NumberofPolesSpinner.Value);
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
            
            
            % Setting the Frequency
            
            freq = num2str(app.FrequencyEditField.Value);
            
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);
           
            
%             % Setting the Speed
%            
%             rpm= num2str(app.SpeedrpmEditField.Value);
%             set_param([bdroot,'/Vbyf with PWM/motor speed'],'Value',rpm);
            
            
            % Setting the Voltage

            Vrms = num2str(round(app.VrmsEditField.Value));
           
            set_param([bdroot,'/GridVoltage'],'Value', Vrms);
                        
            
            % Setting the Torque

            loadTorque = num2str(round(app.LoadTorqueEditField.Value));
            
            set_param([bdroot,'/Input Torque'],'Value', loadTorque);


            % Saving the current status of Simulink
            
            save_system('simulation\ClosedLoopApril1.slx');
            
            % Displaying Message after Setting is succesful
            
            app.SetParametersMessageBox.Visible = true;
            
            app.SetParametersMessageBox.Value='Parameters sent to model';
       
        end

        % Button pushed function: EraseAllButton
        function EraseAllButtonPushed(app, event)
            app.RsEditField.Value=0;
            app.jXsEditField.Value=0;
            app.jXmEditField.Value=0;
            app.jXrEditField.Value=0;
            app.RrEditField.Value=0;
            app.SpeedrpmEditField.Value=0;
            app.NumberofPolesSpinner.Value=0;
            app.FrequencyHzSlider.Value=0;
            app.FrequencyEditField.Value = app.FrequencyHzSlider.Value;
            app.VrmsGridVKnob.Value=0;
            app.VrmsEditField.Value= 0;
            
            app.LoadTorqueKnob.Value=0;
            app.LoadTorqueEditField.Value = 0;
            app.StartButton.Enable ='on';
            app.FuseLamp.Color='white';
            app.SetParametersMessageBox.Visible=false;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            Help();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            app.FuseLamp.Color='green';
            %save_system('simulation/ClosedLoopApril1.slx');
            save_system('simulation\ClosedLoopApril1.slx');
            app.simulated = sim("simulation\ClosedLoopApril1.slx");
            
            FuseArray = app.simulated.Fuse_Result(length(app.simulated.Fuse_Result));
               %The simulation result is a 4*5001 matrix. The approch I
               %took after analyzing the matrix is that once the fuse is
               %blown, the values (the actual spot in the matrix will vary)
               %towards the end will always be zero. Thus taking the last
               %position of the array (array(length(array)) will give the
               %data needed, i.e. if its blown or not.
            plotGraphs(app);
            
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                if(FuseArray==0)
                count=3
                while (count~=0)
                app.FuseLamp.Color='yellow';%blown fuse
                pause(1)
                app.FuseLamp.Color='white';%blown fuse
                pause(1)
                count=count-1;
                end
                app.FuseLamp.Color='yellow';%blown fuse, final state
                elseif  (FuseArray~=0)
                 app.StartButton.Enable ='on';
                 app.FuseLamp.Color='white';            
                end     
            end%end if
            
           %Motor param calculations
           Rs=app.RsEditField.Value;
           Xs=app.jXsEditField.Value;
           Xm=app.jXmEditField.Value;
           Xr=app.jXmEditField.Value;
           Rr=app.RrEditField.Value;
           slip =(2/120)*(3600-frequen)/app.FrequencyEditField.Value;

           R=[(Xm^2)*(Rr/slip)]/[(Xr+Xm)^2+(Rr/slip)^2];
           X= Xm*[Xr*(Xr+Xm)+(Rr/slip)]/[(Xr+Xm)^2+(Rr/slip)^2];
           Z=complex(R+Rs,X+Xs);
           V=360/sqrt(3);
           I=V/Z;
           
           %Motor characteristics
           %R
           app.REditField.Value=num2str(round(R,4));
           %X
           app.XEditField.Value=num2str(round(X,4));
           %Slip
           app.SlipEditField.Value=num2str(100*slip);
           
           %Total impedance
           app.ZEditField.Value=num2str(round(Z,4));
           app.ImpedanceAngle.Value=num2str(angle(round(Z,4))*57.2958);%multiplied because the angle is in rads
           app.ImpedanceAbsolute.Value=num2str(abs(Z));
           
           %Phase current
           app.CurrentEditField.Value=num2str(round(I,2));
           app.PhaseAngle.Value=num2str(angle(round(I,4))*57.2958);%multiplied because the angle is in rads
           app.PhaseAbsoluteEditField.Value=num2str(abs(I)) ; 
           
           %PoweFactor
           pf=round(cos(angle(Z)),4);
           app.PFEditField.Value=num2str(pf);
           
           %Values selection from toWorsPace 2D arrays
           volt=app.simulated.Voltage(length(app.simulated.Voltage));%Takes the last value of the array
           current=app.simulated.Current(length(app.simulated.Current));%Takes the last value of the array
           %is this current rms?
           torque=app.simulated.Torque(length(app.simulated.Torque));%Takes the last value of the array
           
           %Mechanical Torque
           app.TotalMechanicalTorqueNmEditField.Value=num2str(torque);
           
           %Pin
           %Is here rms for voltage good? What about current?
           rms_voltage=360/sqrt(2);
           Pin=pf*rms_voltage*current;
           
           app.SetParametersMessageBox.Visible = 'off';
    
        end

        % Value changed function: ExportDropDown
        function ExportDropDownValueChanged(app, event)
            value = app.ExportDropDown.Value;
            
           switch value
               
               case 'Data (Excel)'
                   
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               t=now;
               d = datetime(floor(t),'ConvertFrom','datenum')%converts it to date and time. floor only gets the date only
               %file creation
               name=strcat('Grid-Lab-Data-',datestr(d),'.xlsx')
               Table_excel = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current)
               writetable(Table_excel,name,'WriteRowNames',true)       
               
               case 'Data (txt)'
                                      
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               t=now;
               d = datetime(floor(t),'ConvertFrom','datenum');%converts it to date and time. floor only gets the date only
               %file creation
               name=strcat('Grid-Lab-Data-',datestr(d),'.txt');
               Table_txt = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current);
               writetable(Table_txt,name,'WriteRowNames',true);   
                   
           end
        end

        % Close request function: GridLaboratoryUIFigure
        function GridLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Experiment1Button.Enable = 'on';
            delete(app)
            
        end

        % Callback function
        function StopTimesDropDown_2ValueChanged(app, event)
            value = app.StopTimesDropDown_2.Value;
            assignin('base','TimeStop',app.StopTimesDropDown.Value);
            sim('base','StopTime',num2str(app.StopTimesDropDown.Value));
                        
            %----General Tab------- ClosedLoopApril1/Motor Model/Rotor speed
            %REFERENCE
            %legend(app.UIAxes_Stator,({'stator current/i-qs','stator current/i-ds'}));
%             plot(app.GraphGeneralVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
%             legend(app.GraphGeneralVoltage,({'Va','Vb','Vc'}));
%             grid(app.GraphGeneralVoltage,'on');
%             
%             plot(app.GraphGeneralCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
%             legend(app.GraphGeneralCurrent,({'Ia','Ib','Ic'}));
%             grid(app.GraphGeneralCurrent,'on');
%             
%             plot(app.GraphGeneralSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
%             grid(app.GraphGeneralSpeed,'on');
%             
%             plot(app.GraphGeneralTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
%             grid(app.GraphGeneralTorque,'on');
%             %---- Each Specific Tab----
%             
%             plot(app.GraphTabTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
%             grid(app.GraphTabTorque,'on');
%             
%             plot(app.GraphTabSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
%             grid(app.GraphTabSpeed,'on');
%             
%             plot(app.GraphTabVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
%             legend(app.GraphTabVoltage,({'Va','Vb','Vc'}));
%             grid(app.GraphTabVoltage,'on');
%             
%             plot(app.GraphTabCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
%             legend(app.GraphTabCurrent,({'Ia','Ib','Ic'}));
%             grid(app.GraphTabCurrent,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GridLaboratoryUIFigure = uifigure('Visible', 'off');
            app.GridLaboratoryUIFigure.Name = 'Grid Laboratory';
            app.GridLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @GridLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GridLaboratoryUIFigure);
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel_2 = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel_2);
            app.PhaseCurrentPanel = uipanel(app.GridLayout7);
            app.PhaseCurrentPanel.Title = 'Phase Current';
            app.GridLayout21_2 = uigridlayout(app.PhaseCurrentPanel);
            app.AbsoluteEditField_4Label = uilabel(app.GridLayout21_2);
            app.AbsoluteEditField_4Label.Text = 'Absolute';
            app.PhaseAbsolute = uieditfield(app.GridLayout21_2, 'text');
            app.AbsoluteEditField_5Label = uilabel(app.GridLayout21_2);
            app.AbsoluteEditField_5Label.Text = 'Absolute';
            app.PhaseAbsoluteEditField = uieditfield(app.GridLayout21_2, 'text');
            app.TotalImpedancePanel = uipanel(app.GridLayout7);
            app.TotalImpedancePanel.Title = 'Total Impedance';
            app.GridLayout21_3 = uigridlayout(app.TotalImpedancePanel);
            app.ZEditField_2Label = uilabel(app.GridLayout21_3);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.GridLayout21_3, 'text');
            app.AbsoluteEditField_6Label = uilabel(app.GridLayout21_3);
            app.AbsoluteEditField_6Label.Text = 'Absolute';
            app.ImpedanceAbsolute = uieditfield(app.GridLayout21_3, 'text');
            app.AngleEditField_2Label_2 = uilabel(app.GridLayout21_3);
            app.AngleEditField_2Label_2.Text = 'Angle';
            app.ImpedanceAngle = uieditfield(app.GridLayout21_3, 'text');
            app.PerformancePanel = uipanel(app.GridLayout7);
            app.PerformancePanel.Title = 'Performance';
            app.GridLayout21_4 = uigridlayout(app.PerformancePanel);
            app.SlipEditFieldLabel = uilabel(app.GridLayout21_4);
            app.SlipEditFieldLabel.Text = 'Slip (%)';
            app.SlipEditField = uieditfield(app.GridLayout21_4, 'text');
            app.PFEditField_2Label = uilabel(app.GridLayout21_4);
            app.PFEditField_2Label.Text = 'PF';
            app.PFEditField_2 = uieditfield(app.GridLayout21_4, 'text');
            app.EfficiencyEditFieldLabel = uilabel(app.GridLayout21_4);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.GridLayout21_4, 'text');
            app.PowerPanel = uipanel(app.GridLayout7);
            app.PowerPanel.Title = 'Power';
            app.GridLayout30 = uigridlayout(app.PowerPanel);
            app.DeliveredPowerPoutEditFieldLabel = uilabel(app.GridLayout30);
            app.DeliveredPowerPoutEditFieldLabel.Text = 'Delivered Power (Pout)';
            app.DeliveredPowerPoutEditField = uieditfield(app.GridLayout30, 'numeric');
            app.TorquePanel = uipanel(app.GridLayout7);
            app.TorquePanel.Title = 'Torque';
            app.GridLayout30_2 = uigridlayout(app.TorquePanel);
            app.TotalMechanicalTorqueNmEditField_2Label = uilabel(app.GridLayout30_2);
            app.TotalMechanicalTorqueNmEditField_2Label.Text = 'Total Mechanical Torque (N.m)';
            app.TotalMechanicalTorqueNmEditField_2 = uieditfield(app.GridLayout30_2, 'numeric');
            app.MotorCharacteristicsPanel = uipanel(app.GridLayout7);
            app.MotorCharacteristicsPanel.Title = 'Motor Characteristics';
            app.GridLayout28 = uigridlayout(app.MotorCharacteristicsPanel);
            app.FrequencyEditField = uieditfield(app.GridLayout28, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.NumberofPolesSpinnerLabel = uilabel(app.GridLayout28);
            app.NumberofPolesSpinnerLabel.Text = 'Number of Poles';
            app.NumberofPolesSpinner = uispinner(app.GridLayout28);
            app.NumberofPolesSpinner.Step = 2;
            app.NumberofPolesSpinner.Limits = [2 4];
            app.NumberofPolesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPolesSpinnerValueChanging, true);
            app.NumberofPolesSpinner.Value = 2;
            app.FrequencyHzSliderLabel = uilabel(app.GridLayout28);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.GridLayout28);
            app.FrequencyHzSlider.Limits = [0 60];
            app.FrequencyHzSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzSliderValueChanging, true);
            app.FrequencyHzSlider.Value = 38.8517909824734;
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel.Text = '3-PHASE INDUCTION MOTOR';
            app.GridVoltage = uipanel(app.GridLayout5);
            app.GridVoltage.Title = 'Grid 3-Phase Voltage';
            app.GridLayout10 = uigridlayout(app.GridVoltage);
            app.VrmsGridVKnob = uiknob(app.GridLayout10, 'continuous');
            app.VrmsGridVKnob.Limits = [0 240];
            app.VrmsGridVKnob.ValueChangedFcn = createCallbackFcn(app, @VrmsGridValueChanging, true);
            app.VrmsGridVKnob.Tag = 'Volts';
            app.VbLamp_11 = uilamp(app.GridLayout10);
            app.VaLabel = uilabel(app.GridLayout10);
            app.VaLabel.Text = 'Va';
            app.VbLamp_12 = uilamp(app.GridLayout10);
            app.VbLamp_13 = uilamp(app.GridLayout10);
            app.VbLabel = uilabel(app.GridLayout10);
            app.VbLabel.Text = 'Vb';
            app.VcLabel = uilabel(app.GridLayout10);
            app.VcLabel.Text = 'Vc';
            app.VrmsEditFieldLabel = uilabel(app.GridLayout10);
            app.VrmsEditFieldLabel.Text = 'Vrms';
            app.VrmsEditField = uieditfield(app.GridLayout10, 'numeric');
            app.VrmsEditField.ValueChangedFcn = createCallbackFcn(app, @VrmsEditFieldValueChanged, true);
            app.VoltageLabel = uilabel(app.GridLayout10);
            app.VoltageLabel.Text = 'Voltage';
            app.ParametersOhmsPanel = uipanel(app.GridLayout5);
            app.ParametersOhmsPanel.Title = 'Parameters (Ohms)';
            app.GridLayout6 = uigridlayout(app.ParametersOhmsPanel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.jXsEditFieldLabel = uilabel(app.GridLayout6);
            app.jXsEditFieldLabel.Text = 'jXs';
            app.jXsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.jXmEditFieldLabel = uilabel(app.GridLayout6);
            app.jXmEditFieldLabel.Text = 'jXm';
            app.jXmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.jXrEditFieldLabel = uilabel(app.GridLayout6);
            app.jXrEditFieldLabel.Text = 'jXr';
            app.jXrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RrEditFieldLabel = uilabel(app.GridLayout6);
            app.RrEditFieldLabel.Text = 'Rr''';
            app.RrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.MotorLoadPanel = uipanel(app.GridLayout5);
            app.MotorLoadPanel.Title = 'Motor Load';
            app.GridLayout16 = uigridlayout(app.MotorLoadPanel);
            app.LoadTorqueLabel = uilabel(app.GridLayout16);
            app.LoadTorqueLabel.Text = 'Load Torque';
            app.LoadTorqueKnob = uiknob(app.GridLayout16, 'continuous');
            app.LoadTorqueKnob.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueKnobValueChanging, true);
            app.TorqueNmLabel = uilabel(app.GridLayout16);
            app.TorqueNmLabel.Text = 'Torque (N*m)';
            app.LoadTorqueEditField = uieditfield(app.GridLayout16, 'numeric');
            app.LoadTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueEditFieldValueChanged, true);
            app.DefaultParametersButton = uibutton(app.GridLayout5, 'push');
            app.DefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultParametersButtonPushed, true);
            app.DefaultParametersButton.Text = 'Default Parameters';
            app.HelpButton = uibutton(app.GridLayout5, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.EraseAllButton = uibutton(app.GridLayout5, 'push');
            app.EraseAllButton.ButtonPushedFcn = createCallbackFcn(app, @EraseAllButtonPushed, true);
            app.EraseAllButton.Text = 'Erase All';
            app.SetParametersMessageBox = uieditfield(app.GridLayout5, 'text');
            app.SetParametersMessageBox.Editable = 'off';
            app.SetParametersMessageBox.Visible = 'off';
            app.Panel_3 = uipanel(app.GridLayout5);
            app.GridLayout26 = uigridlayout(app.Panel_3);
            app.FuseLampLabel = uilabel(app.GridLayout26);
            app.FuseLampLabel.Text = 'Fuse';
            app.FuseLamp = uilamp(app.GridLayout26);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout4 = uigridlayout(app.ResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'-Select-', 'Data (Excel)', 'Data (txt)'};
            app.ExportDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportDropDownValueChanged, true);
            app.ExportDropDown.Value = '-Select-';
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.GeneralResultsTab = uitab(app.TabGroup2);
            app.GeneralResultsTab.Title = 'General Results';
            app.GridLayout23 = uigridlayout(app.GeneralResultsTab);
            app.GraphGeneralVoltage = uiaxes(app.GridLayout23);
            title(app.GraphGeneralVoltage, 'Voltage')
            xlabel(app.GraphGeneralVoltage, 'T(s)')
            ylabel(app.GraphGeneralVoltage, 'V')
            app.GraphGeneralCurrent = uiaxes(app.GridLayout23);
            title(app.GraphGeneralCurrent, 'Current')
            xlabel(app.GraphGeneralCurrent, 'T(s)')
            ylabel(app.GraphGeneralCurrent, 'A')
            app.GraphGeneralSpeed = uiaxes(app.GridLayout23);
            title(app.GraphGeneralSpeed, 'Speed')
            xlabel(app.GraphGeneralSpeed, 'T(s)')
            ylabel(app.GraphGeneralSpeed, 'rpm')
            app.GraphGeneralTorque = uiaxes(app.GridLayout23);
            title(app.GraphGeneralTorque, 'Torque')
            xlabel(app.GraphGeneralTorque, 'T(s)')
            ylabel(app.GraphGeneralTorque, 'N.m')
            app.ElectromagneticTorqueTab = uitab(app.TabGroup2);
            app.ElectromagneticTorqueTab.Title = 'Electro-magnetic Torque';
            app.GridLayout20 = uigridlayout(app.ElectromagneticTorqueTab);
            app.GraphTabTorque = uiaxes(app.GridLayout20);
            title(app.GraphTabTorque, '')
            xlabel(app.GraphTabTorque, 'T(s)')
            ylabel(app.GraphTabTorque, 'N.m')
            app.SpeedTab = uitab(app.TabGroup2);
            app.SpeedTab.Title = 'Speed';
            app.GridLayout25 = uigridlayout(app.SpeedTab);
            app.GraphTabSpeed = uiaxes(app.GridLayout25);
            title(app.GraphTabSpeed, '')
            xlabel(app.GraphTabSpeed, 'T(s)')
            ylabel(app.GraphTabSpeed, 'rpm')
            app.PhaseCurrentsStatorTab = uitab(app.TabGroup2);
            app.PhaseCurrentsStatorTab.Title = 'Phase Currents (Stator)';
            app.GridLayout22 = uigridlayout(app.PhaseCurrentsStatorTab);
            app.GraphTabCurrent = uiaxes(app.GridLayout22);
            title(app.GraphTabCurrent, '')
            xlabel(app.GraphTabCurrent, 'T(s)')
            ylabel(app.GraphTabCurrent, 'A')
            app.VoltageTab = uitab(app.TabGroup2);
            app.VoltageTab.Title = 'Voltage';
            app.GridLayout24 = uigridlayout(app.VoltageTab);
            app.GraphTabVoltage = uiaxes(app.GridLayout24);
            title(app.GraphTabVoltage, '')
            xlabel(app.GraphTabVoltage, 'X')
            ylabel(app.GraphTabVoltage, 'Y')
            app.RefreshButton = uibutton(app.GridLayout4, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.TimeStopSliderLabel = uilabel(app.GridLayout4);
            app.TimeStopSliderLabel.Text = 'Time Stop';
            app.TimeStopSlider = uislider(app.GridLayout4);
            app.TimeStopSlider.Limits = [0 15];
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout21 = uigridlayout(app.ReadMeTab);
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout21);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout21);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            app.StopTimesDropDownLabel = uilabel(app.GridLaboratoryUIFigure);
            app.StopTimesDropDownLabel.Text = 'Stop Time (s)';
            app.StopTimesDropDown = uidropdown(app.GridLaboratoryUIFigure);
            app.StopTimesDropDown.Items = {'2', '4', '6', '8', '10'};
            app.StopTimesDropDown.Value = '2';
            % Show the figure after all components are created
            app.GridLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GridLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GridLaboratoryUIFigure)
        end
    end
end