classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConcordiaUniversityEmulationofanInductionMotorUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Image                matlab.ui.control.Image
        Experiment1Button    matlab.ui.control.Button
        Experiment3Button    matlab.ui.control.Button
        TextArea_6           matlab.ui.control.TextArea
        TextArea_5           matlab.ui.control.TextArea
        TabGroup             matlab.ui.container.TabGroup
        WelcomeTab           matlab.ui.container.Tab
        GridLayout2          matlab.ui.container.GridLayout
        TextArea             matlab.ui.control.TextArea
        GridConnectedTab     matlab.ui.container.Tab
        TextArea_8           matlab.ui.control.TextArea
        VbyFOpenLoopTab      matlab.ui.container.Tab
        GridLayout4          matlab.ui.container.GridLayout
        TextArea_11          matlab.ui.control.TextArea
        VbyFClosedLoopTab    matlab.ui.container.Tab
        GridLayout3          matlab.ui.container.GridLayout
        TextArea_7           matlab.ui.control.TextArea
        TextArea_10          matlab.ui.control.TextArea
        Experiment2Button_2  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Experiment1Object % Description
        Experiment2Object % Description
        Experiment3Object % Description
        %Lab3Object % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.ConcordiaUniversityEmulationofanInductionMotorUIFigure,'center');% Please don't remove this, it's the thrid time I add it. AT
        end

        % Button pushed function: Experiment1Button
        function Experiment1ButtonPushed(app, event)
            app.Experiment1Button.Enable = 'off';
            app.Experiment1Object = GridLab(app);
        end

        % Button pushed function: Experiment3Button
        function Experiment3ButtonPushed2(app, event)
            app.Experiment3Button.Enable = 'off';
            app.Experiment2Object = VbyFClosedLoop(app);
        end

        % Close request function: 
        % ConcordiaUniversityEmulationofanInductionMotorUIFigure
        function ConcordiaUniversityEmulationofanInductionMotorUIFigureCloseRequest(app, event)
            
            delete(app.Experiment1Object)
            delete(app.Experiment2Object)
            delete(app.Experiment3Object)
            delete(app)
            
        end

        % Callback function
        function Experiment3ButtonPushed(app, event)
            app.Experiment3Button.Enable = 'off';
            app.Experiment3Object = VbyFOpenLoop(app);
        end

        % Button pushed function: Experiment2Button_2
        function Experiment2Button_2Pushed(app, event)
             app.Experiment2Button_2.Enable = 'off';
            app.Experiment2Object = VbyFOpenLoop(app);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConcordiaUniversityEmulationofanInductionMotorUIFigure = uifigure('Visible', 'off');
            app.ConcordiaUniversityEmulationofanInductionMotorUIFigure.Name = 'Concordia University - Emulation of an Induction Motor';
            app.ConcordiaUniversityEmulationofanInductionMotorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConcordiaUniversityEmulationofanInductionMotorUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ConcordiaUniversityEmulationofanInductionMotorUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Capture.PNG';
            app.Experiment1Button = uibutton(app.GridLayout, 'push');
            app.Experiment1Button.ButtonPushedFcn = createCallbackFcn(app, @Experiment1ButtonPushed, true);
            app.Experiment1Button.Text = 'Experiment 1';
            app.Experiment3Button = uibutton(app.GridLayout, 'push');
            app.Experiment3Button.ButtonPushedFcn = createCallbackFcn(app, @Experiment3ButtonPushed2, true);
            app.Experiment3Button.Text = 'Experiment 3';
            app.TextArea_6 = uitextarea(app.GridLayout);
            app.TextArea_6.Value = {'Grid Connected'};
            app.TextArea_5 = uitextarea(app.GridLayout);
            app.TextArea_5.Value = {'V by F (Close Loop)'};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.GridLayout2 = uigridlayout(app.WelcomeTab);
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea.Value = {'Welcome to Eruditio! '; ''; 'Eruditio stands for knowledge and learning. Our goal is to provide software-based services that recreates an induction motor. You will be able to use different control schemes, providing you accurate data and visuals to help you reach your learning goals.'};
            app.GridConnectedTab = uitab(app.TabGroup);
            app.GridConnectedTab.Title = 'Grid Connected';
            app.TextArea_8 = uitextarea(app.GridConnectedTab);
            app.TextArea_8.Value = {'An experiment emulating an induction motor connected directly to the Grid. The motor parameters are adjustable (Rs, jXs, jXm, jXr, Rr, and the number of poles) as well as motor inputs (Voltage, frequency, torque.)   '};
            app.VbyFOpenLoopTab = uitab(app.TabGroup);
            app.VbyFOpenLoopTab.Title = 'V by F (OpenLoop)';
            app.GridLayout4 = uigridlayout(app.VbyFOpenLoopTab);
            app.TextArea_11 = uitextarea(app.GridLayout4);
            app.TextArea_11.Value = {'An experiment emulating an induction motor controlled by an open loop v/f control using a PWM. The input speed is used to find a voltage and a frequency that should deliver that speed. The voltage and frequency maintain the same ratio, they grow and shrink in the same proportion to deliver different speeds. That voltage and frequency are put into a Pulse Width Modulation to generate pulses, that control switches connected to a DC battery that power the motor.  '};
            app.VbyFClosedLoopTab = uitab(app.TabGroup);
            app.VbyFClosedLoopTab.Title = 'V by F (Closed Loop)';
            app.GridLayout3 = uigridlayout(app.VbyFClosedLoopTab);
            app.TextArea_7 = uitextarea(app.VbyFClosedLoopTab);
            app.TextArea_7.Value = {'An experiment emulating an induction motor controlled by a closed loop v/f controller using PWM. The closed loop uses feedback, the actual speed the motor is running, to correct the input voltage and frequency to get closer to the desired speed. A PID controller is used to regulate the feedback, and it is possible to change all the variables in that controller and see their effects.  '};
            app.TextArea_10 = uitextarea(app.GridLayout);
            app.TextArea_10.Value = {'V by F (Open Loop)'};
            app.Experiment2Button_2 = uibutton(app.GridLayout, 'push');
            app.Experiment2Button_2.ButtonPushedFcn = createCallbackFcn(app, @Experiment2Button_2Pushed, true);
            app.Experiment2Button_2.Text = 'Experiment 2';
            % Show the figure after all components are created
            app.ConcordiaUniversityEmulationofanInductionMotorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConcordiaUniversityEmulationofanInductionMotorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConcordiaUniversityEmulationofanInductionMotorUIFigure)
        end
    end
end