classdef MainActivity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        WelcomeTab                   matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        DesTextArea                  matlab.ui.control.TextArea
        ControlpannelTab             matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        LoadPanel_3                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        PassiveMotorPanel            matlab.ui.container.Panel
        Switch_2                     matlab.ui.control.ToggleSwitch
        PassivePanel                 matlab.ui.container.Panel
        Switch_3                     matlab.ui.control.ToggleSwitch
        ResistorEditFieldLabel       matlab.ui.control.Label
        ResistorEditField            matlab.ui.control.EditField
        InductorEditFieldLabel       matlab.ui.control.Label
        InductorEditField            matlab.ui.control.EditField
        CapacitorEditFieldLabel      matlab.ui.control.Label
        CapacitorEditField           matlab.ui.control.EditField
        MotorLoadPanel               matlab.ui.container.Panel
        LoadKnobLabel                matlab.ui.control.Label
        LoadKnob                     matlab.ui.control.Knob
        ControlPanel_4               matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        DirectVfVectorPanel          matlab.ui.container.Panel
        Switch                       matlab.ui.control.ToggleSwitch
        DirectPanel                  matlab.ui.container.Panel
        VfPanel                      matlab.ui.container.Panel
        VectorPanel                  matlab.ui.container.Panel
        SourcesPanel_6               matlab.ui.container.Panel
        FEDBYSwitch_6Label           matlab.ui.control.Label
        FEDBYSwitch_6                matlab.ui.control.Switch
        PWMInverterPanel_5           matlab.ui.container.Panel
        VaLamp_11Label               matlab.ui.control.Label
        VaLamp_11                    matlab.ui.control.Lamp
        VbLamp_11Label               matlab.ui.control.Label
        VbLamp_11                    matlab.ui.control.Lamp
        VcLamp_11Label               matlab.ui.control.Label
        VcLamp_11                    matlab.ui.control.Lamp
        VoltageVKnob_11Label         matlab.ui.control.Label
        VoltageVKnob_11              matlab.ui.control.Knob
        maEditField_5Label           matlab.ui.control.Label
        maEditField_5                matlab.ui.control.NumericEditField
        mfEditField_5Label           matlab.ui.control.Label
        mfEditField_5                matlab.ui.control.NumericEditField
        VEditField_11Label           matlab.ui.control.Label
        VEditField_11                matlab.ui.control.NumericEditField
        GridPanel_6                  matlab.ui.container.Panel
        VoltageVKnob_10Label         matlab.ui.control.Label
        VoltageVKnob_10              matlab.ui.control.Knob
        VaLamp_10Label               matlab.ui.control.Label
        VaLamp_10                    matlab.ui.control.Lamp
        VbLamp_10Label               matlab.ui.control.Label
        VbLamp_10                    matlab.ui.control.Lamp
        VcLamp_10Label               matlab.ui.control.Label
        VcLamp_10                    matlab.ui.control.Lamp
        VEditField_10Label           matlab.ui.control.Label
        VEditField_10                matlab.ui.control.NumericEditField
        SequenceControlPanel         matlab.ui.container.Panel
        StartButton                  matlab.ui.control.StateButton
        StopButton                   matlab.ui.control.StateButton
        GraphicalResultsTab          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Image_2                      matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel_2   matlab.ui.control.Label
        SetParametersButton_2        matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        Panel                        matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        XsEditFieldLabel             matlab.ui.control.Label
        XsEditField                  matlab.ui.control.NumericEditField
        XmEditFieldLabel             matlab.ui.control.Label
        XmEditField                  matlab.ui.control.NumericEditField
        XrEditFieldLabel             matlab.ui.control.Label
        XrEditField                  matlab.ui.control.NumericEditField
        Panel2                       matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        FrequencySlider_2Label       matlab.ui.control.Label
        FrequencySlider_2            matlab.ui.control.Slider
        PolesSpinner_2Label          matlab.ui.control.Label
        PolesSpinner_2               matlab.ui.control.Spinner
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        ModeTextAreaLabel            matlab.ui.control.Label
        ModeTextArea                 matlab.ui.control.TextArea
        MotorTextAreaLabel           matlab.ui.control.Label
        MotorTextArea                matlab.ui.control.TextArea
        PWMTextAreaLabel             matlab.ui.control.Label
        PWMTextArea                  matlab.ui.control.TextArea
        HelpTextAreaLabel            matlab.ui.control.Label
        HelpTextArea                 matlab.ui.control.TextArea
        MotorTextArea_2Label         matlab.ui.control.Label
        MotorTextArea_2              matlab.ui.control.TextArea
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function getModelValues(app)
            vDCFromModel =  get_param([bdroot,'/Vdc'],'Value');
            
            app.VEditField_11.Value =  str2double(vDCFromModel);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            movegui(app.UIFigure,'center');
            load_system('simulation\PWMJan27th.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            
            getModelValues(app);
        end

        % Callback function
        function VoltageVKnob_2ValueChanging(app, event)
            changingValue = event.Value;
            voltKnob = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Vdc'],'Value', voltKnob);
            getModelValues(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.GridLayout8 = uigridlayout(app.WelcomeTab);
            app.Image = uiimage(app.GridLayout8);
            app.Image.ImageSource = 'Capture.PNG';
            app.DesTextArea = uitextarea(app.GridLayout8);
            app.DesTextArea.Editable = 'off';
            app.DesTextArea.Value = {'Small description of our goal'};
            app.ControlpannelTab = uitab(app.TabGroup);
            app.ControlpannelTab.Title = 'Control pannel';
            app.GridLayout = uigridlayout(app.ControlpannelTab);
            app.LoadPanel_3 = uipanel(app.GridLayout);
            app.LoadPanel_3.Tooltip = {''};
            app.LoadPanel_3.Title = 'Load';
            app.LoadPanel_3.Tag = 'three states';
            app.GridLayout3 = uigridlayout(app.LoadPanel_3);
            app.PassiveMotorPanel = uipanel(app.GridLayout3);
            app.PassiveMotorPanel.Title = 'Passive Motor';
            app.Switch_2 = uiswitch(app.PassiveMotorPanel, 'toggle');
            app.PassivePanel = uipanel(app.GridLayout3);
            app.PassivePanel.Title = 'Passive';
            app.Switch_3 = uiswitch(app.PassivePanel, 'toggle');
            app.Switch_3.Items = {'Parallel', 'Series'};
            app.Switch_3.Value = 'Parallel';
            app.ResistorEditFieldLabel = uilabel(app.PassivePanel);
            app.ResistorEditFieldLabel.Text = 'Resistor';
            app.ResistorEditField = uieditfield(app.PassivePanel, 'text');
            app.InductorEditFieldLabel = uilabel(app.PassivePanel);
            app.InductorEditFieldLabel.Text = 'Inductor';
            app.InductorEditField = uieditfield(app.PassivePanel, 'text');
            app.CapacitorEditFieldLabel = uilabel(app.PassivePanel);
            app.CapacitorEditFieldLabel.Text = 'Capacitor';
            app.CapacitorEditField = uieditfield(app.PassivePanel, 'text');
            app.MotorLoadPanel = uipanel(app.GridLayout3);
            app.MotorLoadPanel.Title = 'Motor Load';
            app.LoadKnobLabel = uilabel(app.MotorLoadPanel);
            app.LoadKnobLabel.Text = 'Load';
            app.LoadKnob = uiknob(app.MotorLoadPanel, 'continuous');
            app.LoadKnob.Limits = [0 10000000];
            app.ControlPanel_4 = uipanel(app.GridLayout);
            app.ControlPanel_4.Tooltip = {''};
            app.ControlPanel_4.Title = 'Control';
            app.ControlPanel_4.Tag = 'three states';
            app.GridLayout2 = uigridlayout(app.ControlPanel_4);
            app.DirectVfVectorPanel = uipanel(app.GridLayout2);
            app.DirectVfVectorPanel.Title = 'Direct V/f Vector';
            app.Switch = uiswitch(app.DirectVfVectorPanel, 'toggle');
            app.DirectPanel = uipanel(app.GridLayout2);
            app.DirectPanel.Title = 'Direct';
            app.VfPanel = uipanel(app.GridLayout2);
            app.VfPanel.Title = 'V/f';
            app.VectorPanel = uipanel(app.GridLayout2);
            app.VectorPanel.Title = 'Vector';
            app.SourcesPanel_6 = uipanel(app.GridLayout);
            app.SourcesPanel_6.Tooltip = {''};
            app.SourcesPanel_6.Title = 'Sources';
            app.SourcesPanel_6.Tag = 'three states';
            app.FEDBYSwitch_6Label = uilabel(app.SourcesPanel_6);
            app.FEDBYSwitch_6Label.Text = 'FED BY';
            app.FEDBYSwitch_6 = uiswitch(app.SourcesPanel_6, 'slider');
            app.FEDBYSwitch_6.Items = {'GRID', 'INVERTER'};
            app.FEDBYSwitch_6.Value = 'GRID';
            app.PWMInverterPanel_5 = uipanel(app.GridLayout);
            app.PWMInverterPanel_5.Title = 'PWM Inverter';
            app.VaLamp_11Label = uilabel(app.PWMInverterPanel_5);
            app.VaLamp_11Label.Text = 'Va';
            app.VaLamp_11 = uilamp(app.PWMInverterPanel_5);
            app.VbLamp_11Label = uilabel(app.PWMInverterPanel_5);
            app.VbLamp_11Label.Text = 'Vb';
            app.VbLamp_11 = uilamp(app.PWMInverterPanel_5);
            app.VcLamp_11Label = uilabel(app.PWMInverterPanel_5);
            app.VcLamp_11Label.Text = 'Vc';
            app.VcLamp_11 = uilamp(app.PWMInverterPanel_5);
            app.VoltageVKnob_11Label = uilabel(app.PWMInverterPanel_5);
            app.VoltageVKnob_11Label.Text = 'Voltage [V]';
            app.VoltageVKnob_11 = uiknob(app.PWMInverterPanel_5, 'continuous');
            app.VoltageVKnob_11.Limits = [0 250];
            app.maEditField_5Label = uilabel(app.PWMInverterPanel_5);
            app.maEditField_5Label.Text = 'ma';
            app.maEditField_5 = uieditfield(app.PWMInverterPanel_5, 'numeric');
            app.mfEditField_5Label = uilabel(app.PWMInverterPanel_5);
            app.mfEditField_5Label.Text = 'mf';
            app.mfEditField_5 = uieditfield(app.PWMInverterPanel_5, 'numeric');
            app.VEditField_11Label = uilabel(app.PWMInverterPanel_5);
            app.VEditField_11Label.Text = '[V]';
            app.VEditField_11 = uieditfield(app.PWMInverterPanel_5, 'numeric');
            app.VEditField_11.Editable = 'off';
            app.VEditField_11.Tooltip = {'Voltage set by the knob'};
            app.GridPanel_6 = uipanel(app.GridLayout);
            app.GridPanel_6.Title = 'Grid';
            app.VoltageVKnob_10Label = uilabel(app.GridPanel_6);
            app.VoltageVKnob_10Label.Text = 'Voltage [V]';
            app.VoltageVKnob_10 = uiknob(app.GridPanel_6, 'continuous');
            app.VoltageVKnob_10.Limits = [0 250];
            app.VaLamp_10Label = uilabel(app.GridPanel_6);
            app.VaLamp_10Label.Text = 'Va';
            app.VaLamp_10 = uilamp(app.GridPanel_6);
            app.VbLamp_10Label = uilabel(app.GridPanel_6);
            app.VbLamp_10Label.Text = 'Vb';
            app.VbLamp_10 = uilamp(app.GridPanel_6);
            app.VcLamp_10Label = uilabel(app.GridPanel_6);
            app.VcLamp_10Label.Text = 'Vc';
            app.VcLamp_10 = uilamp(app.GridPanel_6);
            app.VEditField_10Label = uilabel(app.GridPanel_6);
            app.VEditField_10Label.Text = '[V]';
            app.VEditField_10 = uieditfield(app.GridPanel_6, 'numeric');
            app.VEditField_10.Editable = 'off';
            app.VEditField_10.Tooltip = {'Voltage set by the knob'};
            app.SequenceControlPanel = uipanel(app.GridLayout);
            app.SequenceControlPanel.Title = 'Sequence Control';
            app.StartButton = uibutton(app.SequenceControlPanel, 'state');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SequenceControlPanel, 'state');
            app.StopButton.Text = 'Stop';
            app.GraphicalResultsTab = uitab(app.TabGroup);
            app.GraphicalResultsTab.Title = 'Graphical Results';
            app.GridLayout4 = uigridlayout(app.GraphicalResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'Data (Excel)', 'Data (txt)', 'Images (pdf)', 'Images (word)'};
            app.ExportDropDown.Value = 'Data (Excel)';
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.GridLayout4);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.GridLayout4);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.GridLayout4);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_5 = uiaxes(app.GridLayout4);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Image_2 = uiimage(app.GridLayout5);
            app.Image_2.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel_2 = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel_2.Text = '3-PHASE INDUCTION MOTOR';
            app.SetParametersButton_2 = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton_2.Text = 'Set Parameters';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'* The values shown are pre-set values. They can be change at any given moment during the lab experiment'};
            app.Panel = uipanel(app.GridLayout5);
            app.Panel.Title = 'Panel';
            app.GridLayout6 = uigridlayout(app.Panel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RsEditField.Value = 1;
            app.XsEditFieldLabel = uilabel(app.GridLayout6);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XmEditFieldLabel = uilabel(app.GridLayout6);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XrEditFieldLabel = uilabel(app.GridLayout6);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.Panel2 = uipanel(app.GridLayout5);
            app.Panel2.Title = 'Panel2';
            app.GridLayout7 = uigridlayout(app.Panel2);
            app.FrequencySlider_2Label = uilabel(app.GridLayout7);
            app.FrequencySlider_2Label.Text = 'Frequency ';
            app.FrequencySlider_2 = uislider(app.GridLayout7);
            app.PolesSpinner_2Label = uilabel(app.GridLayout7);
            app.PolesSpinner_2Label.Text = 'Poles';
            app.PolesSpinner_2 = uispinner(app.GridLayout7);
            app.PolesSpinner_2.Limits = [0 Inf];
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout9 = uigridlayout(app.ReadMeTab);
            app.ModeTextAreaLabel = uilabel(app.GridLayout9);
            app.ModeTextAreaLabel.Text = 'Mode';
            app.ModeTextArea = uitextarea(app.GridLayout9);
            app.ModeTextArea.Editable = 'off';
            app.ModeTextArea.Value = {'The mode pannel is composed of two lights: green and red. The green corresponds to the working mode or "on" mode. The purpose of this app is to emulate an electric drive motor. This visual only shows the student if the speed is correct, such that they do not go into generation mode.'};
            app.MotorTextAreaLabel = uilabel(app.GridLayout9);
            app.MotorTextAreaLabel.Text = 'Motor';
            app.MotorTextArea = uitextarea(app.GridLayout9);
            app.MotorTextArea.Editable = 'off';
            app.MotorTextArea.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.PWMTextAreaLabel = uilabel(app.GridLayout9);
            app.PWMTextAreaLabel.Text = 'PWM';
            app.PWMTextArea = uitextarea(app.GridLayout9);
            app.PWMTextArea.Editable = 'off';
            app.HelpTextAreaLabel = uilabel(app.GridLayout9);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.GridLayout9);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'This option will allow you the student to contact administrators, or security in case of urgence.'};
            app.MotorTextArea_2Label = uilabel(app.GridLayout9);
            app.MotorTextArea_2Label.Text = 'Motor';
            app.MotorTextArea_2 = uitextarea(app.GridLayout9);
            app.MotorTextArea_2.Editable = 'off';
            app.MotorTextArea_2.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout9);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout9);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainActivity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end