% description: This app requires:
% - MATLAB® R2018b or later release
% - Instrument Control Toolbox™
% - A supported oscilloscope instrument; some Tektronix models are supported with the default 'tektronix' driver; other manufacturers/models are supported via third-party IVI-C drivers.
% - Required software for the Quick-Control Oscilloscope interface:
% - "Instrument Control Toolbox Support Package for National Instruments VISA and ICP Interfaces" (install with MATLAB Add-On Explorer)
% - For manufacturers other than Tektronix an installed IVI-C driver compatible with your oscilloscope instrument model
% 2018/12/28, version 1.0, Andrei Ursache
% Copyright 2018 The MathWorks, Inc.
classdef oscilloscopeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OscilloscopeAppUIFigure         matlab.ui.Figure
        ViewPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RefreshPeriodsSpinnerLabel      matlab.ui.control.Label
        RefreshPeriodsSpinner           matlab.ui.control.Spinner
        LivewaveformplotSwitchLabel     matlab.ui.control.Label
        LivewaveformplotSwitch          matlab.ui.control.Switch
        ConnectionPanel                 matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        DriverDropDownLabel             matlab.ui.control.Label
        DriverDropDown                  matlab.ui.control.DropDown
        ResourceDropDownLabel           matlab.ui.control.Label
        ResourceDropDown                matlab.ui.control.DropDown
        ResourceScanButton              matlab.ui.control.Button
        ReadWaveformPanel               matlab.ui.container.Panel
        ReadWaveformButton              matlab.ui.control.Button
        WorkspacevariableEditFieldLabel  matlab.ui.control.Label
        WorkspacevariableEditField      matlab.ui.control.EditField
        ReadWaveformStatus              matlab.ui.control.Label
        OscilloscopeLabel               matlab.ui.control.Label
        AcquisitionPanel                matlab.ui.container.Panel
        AutoSetupButton                 matlab.ui.control.Button
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        VerticalCouplingDropDownLabel   matlab.ui.control.Label
        VerticalCouplingDropDown        matlab.ui.control.DropDown
        VerticalRangeVEditFieldLabel    matlab.ui.control.Label
        VerticalRangeEditField          matlab.ui.control.NumericEditField
        AcquisitionTimesEditFieldLabel  matlab.ui.control.Label
        AcquisitionTimeEditField        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Oscilloscope     % Quick-control oscilloscope object handle
        LivePlotTimer    % Waveform live plot timer object handle
    end

    methods (Access = private)
    
        function closeApp_Callback(app, ~, event)
        %closeApp_Callback Executes after "Close Confirm" dialog window
        %  "Close Confirm" dialog window is launched from AppCloseRequest.
            
            switch event.SelectedOption
                case 'OK'
                    
                    % Disconnect from oscilloscope instrument
                    % (Execute DisconnectButtonPushed callback function)
                    DisconnectButtonPushed(app,[])
                    
                    % Re-enable warnings which where enabled in startupFcn
                    warning('on','instrument:qcinstrument:realValueIsDifferent')
                    
                    delete(app)
                    
                case 'Cancel'
                    % Continue
            end
        end
        
        function TimerFcn_Callback(app,~,~)
        %TimerFcn_Callback Timer callback function which executes periodically
        %  When LivePlotTimer is running this function does periodic waveform live plot updates
            
            y = readWaveform(app.Oscilloscope)';
            acquisitionTime = app.Oscilloscope.AcquisitionTime;
            waveFormLength = app.Oscilloscope.WaveformLength;
            t = linspace(0, acquisitionTime, waveFormLength)';
            plot(app.UIAxes, t, y)
            xlim(app.UIAxes, [t(1) t(end)])
            drawnow
            
        end
        
        function setAppControlsState(app, state)
        %setAppControlsState Updates UI components per specified app state
        %  STATE can be 'scan', 'connected', or 'disconnected'
        
            switch state
                case 'scan'
                    app.ResourceDropDown.Items = {'Scanning resources...'};
                    app.LivewaveformplotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.ResourceDropDown.Enable = 'on';
                    app.ResourceScanButton.Enable = 'off';
                    app.DriverDropDown.Enable = 'off';
                    app.LivewaveformplotSwitch.Enable = 'off';
                    app.ReadWaveformButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.AutoSetupButton.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.VerticalCouplingDropDown.Enable = 'off';
                    app.VerticalRangeEditField.Enable = 'off';
                    app.AcquisitionTimeEditField.Enable = 'off';
                    app.RefreshPeriodsSpinner.Enable = 'off';
                    app.WorkspacevariableEditField.Enable = 'off';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
                case 'connected'
                    app.LivewaveformplotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'on';
                    app.ResourceDropDown.Enable = 'off';
                    app.ResourceScanButton.Enable = 'off';
                    app.DriverDropDown.Enable = 'off';
                    app.LivewaveformplotSwitch.Enable = 'on';
                    app.ReadWaveformButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'on';
                    app.AutoSetupButton.Enable = 'on';
                    app.ChannelDropDown.Enable = 'on';
                    app.VerticalCouplingDropDown.Enable = 'on';
                    app.VerticalRangeEditField.Enable = 'on';
                    app.AcquisitionTimeEditField.Enable = 'on';
                    app.RefreshPeriodsSpinner.Enable = 'on';
                    app.WorkspacevariableEditField.Enable = 'on';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
                    cla(app.UIAxes)
                case 'disconnected'
                    app.LivewaveformplotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'on';
                    app.DisconnectButton.Enable = 'off';
                    app.ResourceDropDown.Enable = 'on';
                    app.ResourceScanButton.Enable = 'on';
                    app.DriverDropDown.Enable = 'on';
                    app.LivewaveformplotSwitch.Enable = 'off';
                    app.ReadWaveformButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.AutoSetupButton.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.VerticalCouplingDropDown.Enable = 'off';
                    app.VerticalRangeEditField.Enable = 'off';
                    app.AcquisitionTimeEditField.Enable = 'off';
                    app.RefreshPeriodsSpinner.Enable = 'off';
                    app.WorkspacevariableEditField.Enable = 'off';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
                case 'setup'
                    app.LivewaveformplotSwitch.Value = 'Off';
                    app.ConnectButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.ResourceDropDown.Enable = 'off';
                    app.ResourceScanButton.Enable = 'off';
                    app.DriverDropDown.Enable = 'off';
                    app.LivewaveformplotSwitch.Enable = 'off';
                    app.ReadWaveformButton.Enable = 'off';
                    app.DisconnectButton.Enable = 'off';
                    app.AutoSetupButton.Enable = 'off';
                    app.ChannelDropDown.Enable = 'off';
                    app.VerticalCouplingDropDown.Enable = 'off';
                    app.VerticalRangeEditField.Enable = 'off';
                    app.AcquisitionTimeEditField.Enable = 'off';
                    app.RefreshPeriodsSpinner.Enable = 'off';
                    app.WorkspacevariableEditField.Enable = 'off';
                    app.ReadWaveformStatus.Text = '';
                    drawnow
            end
            
        end
        
        function results = getDriverNames(~, o)
        %getDriverNames Returns cell array of driver names
        %  Input O is a quick-control oscilloscope object
            
            driverStruct = drivers(o);
            n = numel(driverStruct);
            driverNames = strings(n,1);
            for ii = 1:n
                driverNames(ii) = driverStruct{ii}.Name;
            end
            results = cellstr(driverNames);
        end

        function results = getResourceNames(~, o)
        %getResourceNames Returns cell array of resource names
        %  Input O is a quick-control oscilloscope object
            results = resources(o);
        end
        
        function TimerErrorFcn_Callback(app, ~, ~)
        %TimerErrorFcn_Callback Executes on error event of timer object
        
            app.LivewaveformplotSwitch.Value = 'Off';
            mymessage = {'Cannot keep up with periodic waveform reads.', 'Increase live waveform plot refresh period.'};
            uialert(app.OscilloscopeAppUIFigure, mymessage, 'Waveform read error');
        end
        
        function startLivePlot(app)
        %startLivePlot Starts waveform live plot
        %  Creates a timer object which periodically reads a waveform and updates the live plot
        
            % Stop live plot (if already running)
            stopLivePlot(app)
            
            % Create and configure timer for waveform live plot
            % When LivePlotTimer is running TimerFcn callback will do periodic waveform live plot updates
            app.LivePlotTimer = timer;
            app.LivePlotTimer.TimerFcn = @(obj,event) TimerFcn_Callback(app, obj, event);
            app.LivePlotTimer.Period = app.RefreshPeriodsSpinner.Value;
            app.LivePlotTimer.ExecutionMode = 'fixedRate';
            app.LivePlotTimer.BusyMode = 'error';
            app.LivePlotTimer.TasksToExecute = inf;
            app.LivePlotTimer.StartDelay = 1;
            app.LivePlotTimer.ErrorFcn = @(obj,event) TimerErrorFcn_Callback(app, obj, event);
            start(app.LivePlotTimer)
        end
        
        function stopLivePlot(app)
        %stopLivePlot Stops waveform live plot
        
            if ~isempty(app.LivePlotTimer)
                stop(app.LivePlotTimer)
                delete(app.LivePlotTimer)
                app.LivePlotTimer = [];
            end
        end
        
        function [channelProperties,  channelPropertiesValid] = selectChannel(~, o, channel)
        %selectChannel Selects only specified CHANNEL of oscilloscope object O and disable all other channels
        %  Returns specified channel's VerticalCoupling and VerticalRange property values
        
            disableChannel(o, o.ChannelNames);
            enableChannel(o, channel);
            
            try
                channelProperties.VerticalCoupling = configureChannel(o, channel, 'VerticalCoupling');
                channelProperties.VerticalRange = configureChannel(o, channel, 'VerticalRange');
                channelPropertiesValid = true;
            catch
                channelProperties.VerticalCoupling = '';
                channelProperties.VerticalRange = '';
                channelPropertiesValid = false;
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %startupFcn Creates oscilloscope object and initializes app UI with available drivers and resources
        
            % Create quick-control oscilloscope object
            app.Oscilloscope = oscilloscope;
            
            % Get available oscilloscope drivers at start time
            app.DriverDropDown.Items = getDriverNames(app, app.Oscilloscope);
            
            % Get available oscilloscope resources at start time
            % (execute ResourceScanButton callback function)
            ResourceScanButtonPushed(app,[])
            
            % Disable quick-control oscilloscope warnings such as
            % 'The set value 6.00000e+00 is different from instrument value 5.00000e+00.'
            warning('off','instrument:qcinstrument:realValueIsDifferent')
            
            % Configure app UI controls to correspond to instrument "disconnected" state
            % (Connection will be attempted when Connect button is pushed)
            setAppControlsState(app, 'disconnected');            
            
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
        %ConnectButtonPushed Connects to oscilloscope instrument
        
            try
                setAppControlsState(app, 'setup');
                % Set selected resource and driver
                app.Oscilloscope.Resource = app.ResourceDropDown.Value;
                app.Oscilloscope.Driver = app.DriverDropDown.Value;
                connect(app.Oscilloscope);
            catch exception
                setAppControlsState(app, 'disconnected');
                mymessage = 'Verify that the Resource and Driver names you provided are valid.';
                
                uialert(app.OscilloscopeAppUIFigure, {exception.message, mymessage}, 'Connection error');
                return
            end
            
            % Initialize channel name drop down list with available channel names from oscilloscope instrument
            app.ChannelDropDown.Items = app.Oscilloscope.ChannelNames;
            
            % Configure selected channel
            % (Execute ChannelDropDownValueChanged callback function)
            ChannelDropDownValueChanged(app,[])
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
        %DisconnectButtonPushed Disconnects from oscilloscope instrument
        
                    
            % Stop waveform live plot updates         
            stopLivePlot(app)
        
            try
                disconnect(app.Oscilloscope);
            catch
                % Already disconnected
            end
            
            setAppControlsState(app, 'disconnected');


        end

        % Button pushed function: AutoSetupButton
        function AutoSetupButtonPushed(app, event)
        %AutoSetupButtonPushed Executes AUTOSET routine on oscilloscope instrument
            
            stopLivePlot(app)
            setAppControlsState(app, 'setup');
            autoSetup(app.Oscilloscope);
            
            % Update UI with new channel property values
            % Select the first of the enabled channels after autoset and disable the others
            channel = app.Oscilloscope.ChannelsEnabled{1};
            
            app.ChannelDropDown.Value = channel;
            ChannelDropDownValueChanged(app,[])

        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
        %ChannelDropDownValueChanged Selects a new channel on oscilloscope instrument
            
            value = app.ChannelDropDown.Value;
            stopLivePlot(app)
            setAppControlsState(app, 'setup');
                        
            try
                [channelProperties, channelPropertiesValid] = selectChannel(app, app.Oscilloscope, value);
                if channelPropertiesValid
                    app.VerticalCouplingDropDown.Value = channelProperties.VerticalCoupling;
                    app.VerticalRangeEditField.Value = channelProperties.VerticalRange;
                end
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel error');
            end
            
            app.AcquisitionTimeEditField.Value = app.Oscilloscope.AcquisitionTime;
            
            setAppControlsState(app, 'connected');
            
            % Disable channel property values if invalid
            app.VerticalCouplingDropDown.Enable = channelPropertiesValid;
            app.VerticalRangeEditField.Enable = channelPropertiesValid;
        end

        % Value changed function: VerticalCouplingDropDown
        function VerticalCouplingDropDownValueChanged(app, event)
        %VerticalCouplingDropDown Sets new channel property value on oscilloscope instrument
        
            value = app.VerticalCouplingDropDown.Value;
            try
                configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalCoupling', value);
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel property error');
            end
            app.VerticalCouplingDropDown.Value = configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalCoupling');

        end

        % Value changed function: VerticalRangeEditField
        function VerticalRangeEditFieldValueChanged(app, event)
        %VerticalRangeEditFieldValueChanged Sets new channel property value on oscilloscope instrument
        
            value = app.VerticalRangeEditField.Value;
            try
                configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalRange', value);
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel property error');
            end
            app.VerticalRangeEditField.Value = configureChannel(app.Oscilloscope, app.ChannelDropDown.Value, 'VerticalRange');

        end

        % Value changed function: AcquisitionTimeEditField
        function AcquisitionTimeEditFieldValueChanged(app, event)
        %AcquisitionTimeEditFieldValueChanged Sets new acquisition time value on oscilloscope instrument
        
            value = app.AcquisitionTimeEditField.Value;
            try
                app.Oscilloscope.AcquisitionTime = value;
                app.AcquisitionTimeEditField.Value = app.Oscilloscope.AcquisitionTime;
            catch exception
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Channel property error');
            end
            app.AcquisitionTimeEditField.Value = app.Oscilloscope.AcquisitionTime;
        end

        % Button pushed function: ReadWaveformButton
        function ReadWaveformButtonPushed(app, event)
        %ReadWaveformButtonPushed Reads and saves a single waveform from the oscilloscope instrument
        %  The acquired waveform data is saved to base workspace as a timetable.
        %  If live plot waveform mode is disabled also plot the saved waveform.
            
            channelName = genvarname(app.Oscilloscope.ChannelsEnabled{1});
        
            livePlotEnabled = strcmp(app.LivewaveformplotSwitch.Value, 'On');
            
            % Update status text
            app.ReadWaveformStatus.Text = 'Reading waveform...';
            drawnow
            
            % Temporarely stop waveform live read&plot as it can interfere with waveform read
            stopLivePlot(app)
            
            try
                % If live plot is stopped clear UIAxes in preparation for plotting
                if ~livePlotEnabled
                    cla(app.UIAxes)
                    drawnow
                end
                
                % Read waveform data from oscilloscope instrument
                y = readWaveform(app.Oscilloscope)';
                acquisitionTime = app.Oscilloscope.AcquisitionTime;
                waveFormLength = app.Oscilloscope.WaveformLength;
                
                % Create array of timestamp values
                t = linspace(0, acquisitionTime, waveFormLength)';
                
                % Create waveform data timetable and save to base workspace
                TT = timetable(seconds(t),y, 'VariableNames', {channelName});
                assignin('base', app.WorkspacevariableEditField.Value, TT);
                
                % Update status text
                app.ReadWaveformStatus.Text = 'Waveform saved to workspace.';
                drawnow
                
                if ~livePlotEnabled
                    % If live plot is stopped plot acquired waveform
                    plot(app.UIAxes, t, y)
                    xlim(app.UIAxes, [t(1) t(end)])
                    drawnow
                end
                
            catch exception
                % Update status text
                app.ReadWaveformStatus.Text = '';
                drawnow
                uialert(app.OscilloscopeAppUIFigure, exception.message, 'Waveform read error');
            end
            
            % Restart waveform live plot if it was running
            if livePlotEnabled
                startLivePlot(app)
            end
            
        end

        % Close request function: OscilloscopeAppUIFigure
        function OscilloscopeAppUIFigureCloseRequestFcn(app, event)
        %OscilloscopeAppUIFigureCloseRequestFcn Opens window to request confirmation for app closing
        
            question = 'Close app?';
            uiconfirm(app.OscilloscopeAppUIFigure, question, 'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event));
           
        end

        % Value changed function: RefreshPeriodsSpinner
        function RefreshPeriodsSpinnerValueChanged(app, event)
        %RefreshPeriodsSpinnerValueChanged Updates live plot period if live plot is running
        
            livePlotEnabled = strcmp(app.LivewaveformplotSwitch.Value, 'On');
            if livePlotEnabled
                % Change live plot period
                % Stop live plot and restart it with new period value
                stopLivePlot(app)
                startLivePlot(app)
            end

        end

        % Button pushed function: ResourceScanButton
        function ResourceScanButtonPushed(app, event)
        %ResourceScanButtonPushed Gets available oscilloscope resource names and updates UI
        
            setAppControlsState(app,'scan')
            app.ResourceDropDown.Items = getResourceNames(app, app.Oscilloscope);
            setAppControlsState(app,'disconnected')
        end

        % Value changed function: LivewaveformplotSwitch
        function LivewaveformplotSwitchValueChanged(app, event)
        %LivewaveformplotSwitchValueChanged Starts or stops waveform live plot
        
            value = app.LivewaveformplotSwitch.Value;
            switch value
                case 'On'
                    startLivePlot(app)
                case 'Off'
                    stopLivePlot(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OscilloscopeAppUIFigure = uifigure('Visible', 'off');
            app.OscilloscopeAppUIFigure.Name = 'Oscilloscope App';
            app.OscilloscopeAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @OscilloscopeAppUIFigureCloseRequestFcn, true);
            app.ViewPanel = uipanel(app.OscilloscopeAppUIFigure);
            app.UIAxes = uiaxes(app.ViewPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.RefreshPeriodsSpinnerLabel = uilabel(app.ViewPanel);
            app.RefreshPeriodsSpinnerLabel.Text = 'Refresh Period (s)';
            app.RefreshPeriodsSpinner = uispinner(app.ViewPanel);
            app.RefreshPeriodsSpinner.Limits = [0.5 30];
            app.RefreshPeriodsSpinner.ValueChangedFcn = createCallbackFcn(app, @RefreshPeriodsSpinnerValueChanged, true);
            app.RefreshPeriodsSpinner.Value = 5;
            app.LivewaveformplotSwitchLabel = uilabel(app.ViewPanel);
            app.LivewaveformplotSwitchLabel.Text = 'Live waveform plot';
            app.LivewaveformplotSwitch = uiswitch(app.ViewPanel, 'slider');
            app.LivewaveformplotSwitch.ValueChangedFcn = createCallbackFcn(app, @LivewaveformplotSwitchValueChanged, true);
            app.ConnectionPanel = uipanel(app.OscilloscopeAppUIFigure);
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.DriverDropDownLabel = uilabel(app.ConnectionPanel);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DriverDropDown = uidropdown(app.ConnectionPanel);
            app.DriverDropDown.Items = {};
            app.DriverDropDown.Value = {};
            app.ResourceDropDownLabel = uilabel(app.ConnectionPanel);
            app.ResourceDropDownLabel.Text = 'Resource';
            app.ResourceDropDown = uidropdown(app.ConnectionPanel);
            app.ResourceDropDown.Items = {};
            app.ResourceDropDown.Value = {};
            app.ResourceScanButton = uibutton(app.ConnectionPanel, 'push');
            app.ResourceScanButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceScanButtonPushed, true);
            app.ResourceScanButton.Text = 'Scan';
            app.ReadWaveformPanel = uipanel(app.OscilloscopeAppUIFigure);
            app.ReadWaveformButton = uibutton(app.ReadWaveformPanel, 'push');
            app.ReadWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @ReadWaveformButtonPushed, true);
            app.ReadWaveformButton.Text = 'Read Waveform';
            app.WorkspacevariableEditFieldLabel = uilabel(app.ReadWaveformPanel);
            app.WorkspacevariableEditFieldLabel.Text = 'Workspace variable';
            app.WorkspacevariableEditField = uieditfield(app.ReadWaveformPanel, 'text');
            app.WorkspacevariableEditField.Value = 'mywaveform';
            app.ReadWaveformStatus = uilabel(app.ReadWaveformPanel);
            app.ReadWaveformStatus.Text = '';
            app.OscilloscopeLabel = uilabel(app.OscilloscopeAppUIFigure);
            app.OscilloscopeLabel.Text = 'Oscilloscope';
            app.AcquisitionPanel = uipanel(app.OscilloscopeAppUIFigure);
            app.AutoSetupButton = uibutton(app.AcquisitionPanel, 'push');
            app.AutoSetupButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSetupButtonPushed, true);
            app.AutoSetupButton.Text = 'Auto Setup';
            app.ChannelDropDownLabel = uilabel(app.AcquisitionPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.AcquisitionPanel);
            app.ChannelDropDown.Items = {'CH1'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'CH1';
            app.VerticalCouplingDropDownLabel = uilabel(app.AcquisitionPanel);
            app.VerticalCouplingDropDownLabel.Text = 'Vertical Coupling';
            app.VerticalCouplingDropDown = uidropdown(app.AcquisitionPanel);
            app.VerticalCouplingDropDown.Items = {'AC', 'DC', 'GND'};
            app.VerticalCouplingDropDown.ValueChangedFcn = createCallbackFcn(app, @VerticalCouplingDropDownValueChanged, true);
            app.VerticalCouplingDropDown.Value = 'AC';
            app.VerticalRangeVEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.VerticalRangeVEditFieldLabel.Text = 'Vertical Range (V)';
            app.VerticalRangeEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.VerticalRangeEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalRangeEditFieldValueChanged, true);
            app.VerticalRangeEditField.Value = 1;
            app.AcquisitionTimesEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.AcquisitionTimesEditFieldLabel.Text = 'Acquisition Time (s)';
            app.AcquisitionTimeEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.AcquisitionTimeEditField.ValueDisplayFormat = '%.3e';
            app.AcquisitionTimeEditField.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTimeEditFieldValueChanged, true);
            app.AcquisitionTimeEditField.Value = 0.0001;
            % Show the figure after all components are created
            app.OscilloscopeAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oscilloscopeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OscilloscopeAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OscilloscopeAppUIFigure)
        end
    end
end